classdef main < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        SegmentasiImageLabel    matlab.ui.control.Label
        EdgeLabel               matlab.ui.control.Label
        InputLabel              matlab.ui.control.Label
        ImageSegment            matlab.ui.control.Image
        ImageEdge               matlab.ui.control.Image
        ImageInput              matlab.ui.control.Image
        FillThresholdEditField  matlab.ui.control.NumericEditField
        FillThresholdLabel      matlab.ui.control.Label
        RadiusEditField         matlab.ui.control.NumericEditField
        RadiusEditFieldLabel    matlab.ui.control.Label
        CannyButton             matlab.ui.control.Button
        RobertsButton           matlab.ui.control.Button
        PrewittButton           matlab.ui.control.Button
        SobelButton             matlab.ui.control.Button
        LoGButton               matlab.ui.control.Button
        LaplacianButton         matlab.ui.control.Button
        SegmentasiLabel         matlab.ui.control.Label
        PilihCitraButton        matlab.ui.control.Button
    end

    methods (Access = public)
        function res = laplacianEdge(~, image)
            mask = [0 1 0;
                    1 -4 1;
                    0 1 0];
            res = uint8(conv2(double(rgb2gray(image)), double(mask), 'same'));
        end

        function res = logEdge(~, image)
            mask = [0 0 -1 0 0;
                    0 -1 -2 -1 0;
                    -1 -2 16 -2 -1;
                    0 -1 -2 -1 0;
                    0 0 -1 0 0];
            res = uint8(conv2(double(rgb2gray(image)), double(mask), 'same'));
        end
        
        function res = sobelEdge(~, image)
            maskX = [-1 0 1;
                    -2 0 2;
                    -1 0 1];
            maskY = [1 2 1;
                     0 0 0;
                    -1 -2 -1];
            sobelJX = conv2(double(rgb2gray(image)), double(maskX), 'same');
            sobelJY = conv2(double(rgb2gray(image)), double(maskY), 'same');
            res = uint8(sqrt(sobelJX.^2 + sobelJY.^2));
        end

        function res = prewittEdge(~, image)
            maskX = [-1 0 1;
                    -1 0 1;
                    -1 0 1];
            maskY = [-1 -1 -1;
                    -1 0 1;
                    -1 0 1];
            prewittX = conv2(double(rgb2gray(image)), double(maskX), 'same');
            prewittY = conv2(double(rgb2gray(image)), double(maskY), 'same');
            res = uint8(sqrt(prewittX.^2 + prewittY.^2));
        end

        function res = robertsEdge(~, image)
            maskX = [1 0;
                    0 -1];
            maskY = [0 1;
                    -1 0];
            robertsX = conv2(double(rgb2gray(image)), double(maskX), 'same');
            robertsY = conv2(double(rgb2gray(image)), double(maskY), 'same');
            res = uint8(sqrt(robertsX.^2 + robertsY.^2));
        end

        function res = cannyEdge(~, image)
            res = edge(im2gray(image), 'canny');
        end


        function res = segmentation(~, image, imageEdge, rad, thres)
             binarizedEdge = imbinarize(imageEdge, graythresh(imageEdge));
             closedEdge = imclose(binarizedEdge, strel('disk', rad));
             filledEdge = imfill(closedEdge, 'holes');
             

             filtered = imopen(filledEdge, strel(ones(3, 3)));
             mask = uint8(bwareaopen(filtered, thres));

             r = image(:, :, 1) .* mask;
             g = image(:, :, 2) .* mask;
             b = image(:, :, 3) .* mask;
             res = cat(3, r, g, b);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)

        end

        % Button pushed function: PilihCitraButton
        function PilihCitraButtonPushed(comp, event)
            % preventing main GUI from minimizing after uigetfile
            dummyWindow = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.tif;*.bmp'});
            if file ~= 0
                comp.SegmentasiLabel.Text = strcat(path, file);
                comp.ImageInput.ImageSource = imread(comp.SegmentasiLabel.Text);
            end
            delete(dummyWindow);
        end

        % Button pushed function: LaplacianButton
        function JalankanLaplacian(comp, event)
            try
                image = imread(comp.SegmentasiLabel.Text);

                % Laplacian
                edge = comp.laplacianEdge(image);
                comp.ImageEdge.ImageSource = cat(3, edge, edge, edge);
                % Segmentasi
                res = comp.segmentation(image, edge, ...
                    comp.RadiusEditField.Value, ...
                    comp.FillThresholdEditField.Value);
                comp.ImageSegment.ImageSource = res;
            catch e
                msgbox(e.message, "Error", "error");
            end
        end

        % Button pushed function: LoGButton
        function JalankanLoG(comp, event)
             try
                image = imread(comp.SegmentasiLabel.Text);

                % LoG
                edge = comp.logEdge(image);
                comp.ImageEdge.ImageSource = cat(3, edge, edge, edge);
                % Segmentasi
                res = comp.segmentation(image, edge, ...
                    comp.RadiusEditField.Value, ...
                    comp.FillThresholdEditField.Value);
                comp.ImageSegment.ImageSource = res;
            catch e
                msgbox(e.message, "Error", "error");
            end
        end

        % Button pushed function: SobelButton
        function JalankanSobel(comp, event)
             try
                image = imread(comp.SegmentasiLabel.Text);

                % Sobel
                edge = comp.sobelEdge(image);
                comp.ImageEdge.ImageSource = cat(3, edge, edge, edge);
                % Segmentasi
                res = comp.segmentation(image, edge, ...
                    comp.RadiusEditField.Value, ...
                    comp.FillThresholdEditField.Value);
                comp.ImageSegment.ImageSource = res;
            catch e
                msgbox(e.message, "Error", "error");
            end 
        end

        % Button pushed function: PrewittButton
        function JalankanPrewitt(comp, event)
             try
                image = imread(comp.SegmentasiLabel.Text);

                % Prewitt
                edge = comp.prewittEdge(image);
                comp.ImageEdge.ImageSource = cat(3, edge, edge, edge);
                % Segmentasi
                res = comp.segmentation(image, edge, ...
                    comp.RadiusEditField.Value, ...
                    comp.FillThresholdEditField.Value);
                comp.ImageSegment.ImageSource = res;
            catch e
                msgbox(e.message, "Error", "error");
            end 
        end

        % Button pushed function: RobertsButton
        function JalankanRoberts(comp, event)
             try
                image = imread(comp.SegmentasiLabel.Text);

                % Roberts
                edge = comp.robertsEdge(image);
                comp.ImageEdge.ImageSource = cat(3, edge, edge, edge);
                % Segmentasi
                res = comp.segmentation(image, edge, ...
                    comp.RadiusEditField.Value, ...
                    comp.FillThresholdEditField.Value);
                comp.ImageSegment.ImageSource = res;
            catch e
                msgbox(e.message, "Error", "error");
            end 
        end

        % Button pushed function: CannyButton
        function JalankanCanny(comp, event)
             try
                image = imread(comp.SegmentasiLabel.Text);

                % Canny
                edge = comp.cannyEdge(image);
                edge = uint8(edge) .* 256; % Binary to 256
                comp.ImageEdge.ImageSource = cat(3, edge, edge, edge);
                % Segmentasi
                res = comp.segmentation(image, edge, ...
                    comp.RadiusEditField.Value, ...
                    comp.FillThresholdEditField.Value);
                comp.ImageSegment.ImageSource = res;
            catch e
                msgbox(e.message, "Error", "error");
            end 
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 582 489];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.PilihCitraButton = uibutton(comp, 'push');
            comp.PilihCitraButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PilihCitraButtonPushed, true);
            comp.PilihCitraButton.Text = 'Pilih Citra';
            comp.SegmentasiLabel = uilabel(comp);
            comp.SegmentasiLabel.Text = 'Pilih citra input untuk dilakukan segmentasi';
            comp.LaplacianButton = uibutton(comp, 'push');
            comp.LaplacianButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanLaplacian, true);
            comp.LaplacianButton.Text = 'Laplacian';
            comp.LoGButton = uibutton(comp, 'push');
            comp.LoGButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanLoG, true);
            comp.LoGButton.Text = 'LoG';
            comp.SobelButton = uibutton(comp, 'push');
            comp.SobelButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanSobel, true);
            comp.SobelButton.Text = 'Sobel';
            comp.PrewittButton = uibutton(comp, 'push');
            comp.PrewittButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanPrewitt, true);
            comp.PrewittButton.Text = 'Prewitt';
            comp.RobertsButton = uibutton(comp, 'push');
            comp.RobertsButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanRoberts, true);
            comp.RobertsButton.Text = 'Roberts';
            comp.CannyButton = uibutton(comp, 'push');
            comp.CannyButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JalankanCanny, true);
            comp.CannyButton.Text = 'Canny';
            comp.RadiusEditFieldLabel = uilabel(comp);
            comp.RadiusEditFieldLabel.Text = 'Radius';
            comp.RadiusEditField = uieditfield(comp, 'numeric');
            comp.RadiusEditField.Value = 15;
            comp.FillThresholdLabel = uilabel(comp);
            comp.FillThresholdLabel.Text = 'Fill Threshold';
            comp.FillThresholdEditField = uieditfield(comp, 'numeric');
            comp.FillThresholdEditField.Value = 1000;
            comp.ImageInput = uiimage(comp);
            comp.ImageEdge = uiimage(comp);
            comp.ImageSegment = uiimage(comp);
            comp.InputLabel = uilabel(comp);
            comp.InputLabel.Text = 'Input';
            comp.EdgeLabel = uilabel(comp);
            comp.EdgeLabel.Text = 'Edge';
            comp.SegmentasiImageLabel = uilabel(comp);
            comp.SegmentasiImageLabel.Text = 'Segmentasi';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end